classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlugFinderUIFigure         matlab.ui.Figure
        MaleAxes                   matlab.ui.control.UIAxes
        FemaleAxes                 matlab.ui.control.UIAxes
        LoadMalePlugImageButton    matlab.ui.control.Button
        LoadFemalePlugImageButton  matlab.ui.control.Button
        CalculateButton            matlab.ui.control.Button
        CalculateButton_2          matlab.ui.control.Button
        USBLabel                   matlab.ui.control.Label
        HDMILabel                  matlab.ui.control.Label
        AUXLabel                   matlab.ui.control.Label
        MouseLabel                 matlab.ui.control.Label
        VGALabel                   matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label2                     matlab.ui.control.Label
        HTML                       matlab.ui.control.HTML
        HTML_2                     matlab.ui.control.HTML
        HTML_3                     matlab.ui.control.HTML
        HTML_4                     matlab.ui.control.HTML
        HTML_5                     matlab.ui.control.HTML
    end

% Author: Daniel Ecker 1426753    
    properties (Access = private)
        maleIm %maleImage
        femaleIm %femaleImage
    end


    methods (Access = private)
        
        function updatemaleimage(app, fname)

                try
                    im = imread(fname);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.PlugFinderUIFigure, ME.message, 'Image Error');
                    return;
                end 
            file = split(fname,'\')
            app.Label.Text = file(length(file))
            app.Label2.Text = 'File loaded'
                app.maleIm = im;
                imshow(im,'parent',app.MaleAxes);
        end
        
        function updatefemaleimage(app, fname)
                try
                    im = imread(fname);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.PlugFinderUIFigure, ME.message, 'Image Error');
                    return;
                end 
            file = split(fname,'\')
            app.Label_2.Text = file(length(file))
            app.Label2.Text = 'File loaded'
                app.femaleIm = im;
                imshow(im,'parent',app.FemaleAxes);
        end
        
        
        function CalcFemales(app)
            
    templateSigma = 3;
    imageSigma = 2;
    templateSchwellenwert_Low = 0.055;
    templateSchwellenwert_High = 0.125;
    imageSchwellenwert_Low = 0.035;
    imageSchwellenwert_High = 0.095;
	mainFuncFemale(app,app.femaleIm, templateSigma, imageSigma, imageSchwellenwert_Low, imageSchwellenwert_High, templateSchwellenwert_Low, templateSchwellenwert_High);
        end
        
        function CalcMales(app)
            
    templateSigma = 3;
    imageSigma = 2;
    templateSchwellenwert_Low = 0.055;
    templateSchwellenwert_High = 0.125;
    imageSchwellenwert_Low = 0.055;
    imageSchwellenwert_High = 0.125;
	mainFuncMale(app,app.maleIm, templateSigma, imageSigma, imageSchwellenwert_Low, imageSchwellenwert_High, templateSchwellenwert_Low, templateSchwellenwert_High);
        end
        
               function mainFuncFemale(app,original, templateSigma, imageSigma, imageT_Low, imageT_High, templateT_Low, templateT_High)


		usb = 1;
		hdmi = 2;
		aux = 3;
		mouse = 4;
		vga = 5;

                app.Label2.Text = 'Select Cropping Area'
                
		% cropping
		cutResult = GeoTransformation.justClipp(original);
		imageCut = cutResult.image;
		%rectPos = cutResult.recPos;

  
                app.Label2.Text = 'Looking for female Ports...'  
                drawnow
  
		% resize
		imageCut = imresize(imageCut, [600,164]);


		% apply filters to original for processing

		%image = GaussFilter.Filter(image);
		image = EdgeDetection.Filter(imageCut, imageSigma, imageT_Low, imageT_High);
		%image = BinaryImage.Binary(image);

		% load templates
		hdmiTemplate = imread('Images/hdmi_template_4.jpg');
		vgaTemplate = imread('Images/vga_template_1.jpg');
		usbTemplate = imread('Images/usb_template_2.jpg');
		auxTemplate = imread('Images/aux_template_2.jpg');
		mouseTemplate = imread('Images/mouse_template_1.jpg');


		% apply filters to templates for processing

		%Gauss
		%hdmiTemplate = GaussFilter.Filter(hdmiTemplate, 0);
		%usbTemplate = GaussFilter.Filter(usbTemplate, 0);
		%auxTemplate = GaussFilter.Filter(auxTemplate, 0);

		%Edge Detection
  		mouseTemplate = imresize(EdgeDetection.Filter(mouseTemplate, templateSigma, templateT_Low, templateT_High), [60,60]);
        auxTemplate = imresize(EdgeDetection.Filter(auxTemplate, templateSigma, templateT_Low, templateT_High), [50,50]);
		vgaTemplate = imresize(EdgeDetection.Filter(vgaTemplate, templateSigma, templateT_Low, templateT_High), [165,60]);
        usbTemplate = imresize(EdgeDetection.Filter(usbTemplate, templateSigma, templateT_Low, templateT_High), [80,40]);
		hdmiTemplate = imresize(EdgeDetection.Filter(hdmiTemplate, templateSigma, templateT_Low, templateT_High), [80,35]);

		%Binary
		%hdmiTemplate = BinaryImage.Binary(hdmiTemplate);
		%usbTemplate = BinaryImage.Binary(usbTemplate);
		%auxTemplate = BinaryImage.Binary(auxTemplate);


		Matches = []; % x; y; height; width; score; type
		scales = [0.7, 0.8];
		
		% find matches
  app.Label2.Text = 'Looking for Mouse Ports...'  
  drawnow
        disp('mouse');
		Matches = TemplateMatching.Match(Matches, image, mouseTemplate, mouse, 0.30, 70, scales);
  app.Label2.Text = 'Looking for VGA Ports...'  
  drawnow
		disp('vga');
		Matches = TemplateMatching.Match(Matches, image, vgaTemplate, vga, 0.15, 70, scales);
  app.Label2.Text = 'Looking for AUX Ports...'  
  drawnow
		disp('aux');
		Matches = TemplateMatching.Match(Matches, image, auxTemplate, aux, 0.28, 70, scales);
  app.Label2.Text = 'Looking for USB Ports...'  
  drawnow
        disp('usb');
		Matches = TemplateMatching.Match(Matches, image, usbTemplate, usb, 0.37, 70, scales);
  app.Label2.Text = 'Looking for HDMI Ports...'  
  drawnow
		disp('hdmi');
		Matches = TemplateMatching.Match(Matches, image, hdmiTemplate, hdmi, 0.37, 70, scales);
		

		format shortg
		disp(Matches);

		% highlight matches in original
		result = TemplateMatching.DrawRectangles(imageCut, Matches);


		% SHOW RESULT IN APP
		imshow(result, 'parent',app.FemaleAxes);
	        disp('Female Ports Matched');
          app.Label2.Text = 'Finished!'  
	
end
        
        function mainFuncMale(app,original, templateSigma, imageSigma, imageT_Low, imageT_High, templateT_Low, templateT_High)
            
            disp('Find Male Plugs...')
            app.Label2.Text = 'Looking for male Ports...' 
            drawnow
		usb = 1;
		hdmi = 2;
		
		[x,y,~] = size(original);
		
		maxy = 800;
		maxx = 800;
		
		if y > maxy
			original = imresize(original, [(x * (maxy/y)), maxy]);
			
		end
		
		if x > maxx
			original = imresize(original, [maxx, (y * (maxx/x))]);
		end
		
		image = EdgeDetection.Filter(original, imageSigma, imageT_Low, imageT_High);

		% load templates
        usbTemplate = imread('Images/male_usb_template.jpg');
		hdmiTemplate = imread('Images/male_hdmi_template1.jpg');
		
		usbTemplate = EdgeDetection.Filter(usbTemplate, templateSigma, templateT_Low, templateT_High);
		hdmiTemplate = EdgeDetection.Filter(hdmiTemplate, templateSigma, templateT_Low, templateT_High);

		Matches = [];
		
		%scales = flip(linspace(0.4, max((maxx/x),(maxy/y)),7)); % 7 sizes up to the size of scaled image 
		scales = flip(linspace(0.2, 2, 20)); % 20 sizes between 0.2 and 2 
 app.Label2.Text = 'Looking for HDMI Ports...'  
 drawnow
        disp('hdmi');
		Matches = TemplateMatching.Match(Matches, image, hdmiTemplate, hdmi, 0.40, 200, scales);
  app.Label2.Text = 'Looking for USB Ports...'  
  drawnow
		disp('usb');
		Matches = TemplateMatching.Match(Matches, image, usbTemplate, usb, 0.53, 200, scales);
		
		format shortg
		disp(Matches);

		% highlight matches in original
		result = TemplateMatching.DrawRectangles(original, Matches);
  
                %SHOW RESULT IN APP
		imshow(result, 'parent',app.MaleAxes);
  app.Label2.Text = 'Finished!'  
	
	
end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %updatemaleimage(app,'Images/port_1.jpg')
            %updatefemaleimage(app,'Images/image_6.jpg')
        end

        % Button pushed function: LoadMalePlugImageButton
        function LoadMalePlugImageButtonPushed(app, event)
            %Get current Directory for this app
            [directory,~,~] = fileparts(mfilename('fullpath'));
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.png','Image Files'};
            [f, p] = uigetfile(filterspec,'Open Male Plug Image',strcat(directory,'/Images'));
            addpath(genpath(directory));
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updatemaleimage(app, fname);
            end
        end

        % Button pushed function: LoadFemalePlugImageButton
        function LoadFemalePlugImageButtonPushed(app, event)
            %Get current Directory for this app
            [directory,~,~] = fileparts(mfilename('fullpath'));
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.png','Image Files'};
            [f, p] = uigetfile(filterspec,'Open Female Plug Image',strcat(directory,'/Images'));
            addpath(genpath(directory));
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updatefemaleimage(app, fname);
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if ~isempty(app.maleIm) 
                CalcMales(app)
            end
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
             if ~isempty(app.femaleIm) 
                CalcFemales(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlugFinderUIFigure = uifigure('Visible', 'off');
            app.PlugFinderUIFigure.Name = 'Plug Finder';
            app.MaleAxes = uiaxes(app.PlugFinderUIFigure);
            title(app.MaleAxes, 'Male Plugs')
            xlabel(app.MaleAxes, '')
            ylabel(app.MaleAxes, '')
            app.MaleAxes.XDir = 'reverse';
            app.MaleAxes.YDir = 'reverse';
            app.MaleAxes.XTick = [];
            app.MaleAxes.YTick = [];
            app.FemaleAxes = uiaxes(app.PlugFinderUIFigure);
            title(app.FemaleAxes, 'Female Plugs')
            xlabel(app.FemaleAxes, '')
            ylabel(app.FemaleAxes, '')
            app.FemaleAxes.XTick = [];
            app.FemaleAxes.YTick = [];
            app.LoadMalePlugImageButton = uibutton(app.PlugFinderUIFigure, 'push');
            app.LoadMalePlugImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMalePlugImageButtonPushed, true);
            app.LoadMalePlugImageButton.Text = {'Load Male Plug Image'; ''};
            app.LoadFemalePlugImageButton = uibutton(app.PlugFinderUIFigure, 'push');
            app.LoadFemalePlugImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFemalePlugImageButtonPushed, true);
            app.LoadFemalePlugImageButton.Text = {'Load Female Plug Image'; ''};
            app.CalculateButton = uibutton(app.PlugFinderUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = {'Calculate'; ''};
            app.CalculateButton_2 = uibutton(app.PlugFinderUIFigure, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = {'Calculate'; ''};
            app.USBLabel = uilabel(app.PlugFinderUIFigure);
            app.USBLabel.Text = 'USB';
            app.HDMILabel = uilabel(app.PlugFinderUIFigure);
            app.HDMILabel.Text = 'HDMI';
            app.AUXLabel = uilabel(app.PlugFinderUIFigure);
            app.AUXLabel.Text = 'AUX';
            app.MouseLabel = uilabel(app.PlugFinderUIFigure);
            app.MouseLabel.Text = 'Mouse';
            app.VGALabel = uilabel(app.PlugFinderUIFigure);
            app.VGALabel.Text = 'VGA';
            app.Label = uilabel(app.PlugFinderUIFigure);
            app.Label_2 = uilabel(app.PlugFinderUIFigure);
            app.Label2 = uilabel(app.PlugFinderUIFigure);
            app.Label2.Text = 'Choose File(s) to start';
            app.HTML = uihtml(app.PlugFinderUIFigure);
            app.HTML.HTMLSource = '<!DOCTYPE html><html>   <head>   </head>   <body style="background-color:#00FF00;">   </body></html>';
            app.HTML_2 = uihtml(app.PlugFinderUIFigure);
            app.HTML_2.HTMLSource = '<!DOCTYPE html><html>   <head>   </head>   <body style="background-color:#FFFF00;">   </body></html>';
            app.HTML_3 = uihtml(app.PlugFinderUIFigure);
            app.HTML_3.HTMLSource = '<!DOCTYPE html><html>   <head>   </head>   <body style="background-color:#FF0000;">   </body></html>';
            app.HTML_4 = uihtml(app.PlugFinderUIFigure);
            app.HTML_4.HTMLSource = '<!DOCTYPE html><html>   <head>   </head>   <body style="background-color:#0000FF;">   </body></html>';
            app.HTML_5 = uihtml(app.PlugFinderUIFigure);
            app.HTML_5.HTMLSource = '<!DOCTYPE html><html>   <head>   </head>   <body style="background-color:#FF00FF;">   </body></html>';
            % Show the figure after all components are created
            app.PlugFinderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlugFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlugFinderUIFigure)
        end
    end
end