classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MaleButton            matlab.ui.control.Button
        FemaleButton          matlab.ui.control.Button
        ZuschneidenButton     matlab.ui.control.Button
        BildauswaehlenButton  matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Picture % Picture, which gets read in and edited
    end
    
    methods (Access = private, Static)
        
        function Cut(app, pic)
            app.Picture = GeoTransformation.justClipp(pic);
            imshow(app.Picture.image, 'Parent', app.UIAxes);
        end
        
        function female(app)
            %mainFunc(app.Picture.image, 1);
            usb = 1;
		hdmi = 2;
		aux = 3;
		mouse = 4;
		vga = 5;

		% cropping
		%cutResult = GeoTransformation.justClipp(original);
		%imageCut = cutResult.image;
		%rectPos = cutResult.recPos;

		% resize
		imageCut = imresize(app.Picture.image, [600,164]);


		% apply filters to original for processing

		%image = GaussFilter.Filter(image);
		image = EdgeDetection.Filter(imageCut, 'sobel');
		%image = BinaryImage.Binary(image);

		% load templates
		hdmiTemplate = imread('Images/hdmi_template_3.jpg');
		vgaTemplate = imread('Images/vga_template_1.jpg');
		usbTemplate = imread('Images/usb_template_2.jpg');
		auxTemplate = imread('Images/aux_template_2.jpg');
		mouseTemplate = imread('Images/mouse_template_1.jpg');


		% apply filters to templates for processing

		%Gauss
		%hdmiTemplate = GaussFilter.Filter(hdmiTemplate, 0);
		%usbTemplate = GaussFilter.Filter(usbTemplate, 0);
		%auxTemplate = GaussFilter.Filter(auxTemplate, 0);

		%Edge Detection
		hdmiTemplate = imresize(EdgeDetection.Filter(hdmiTemplate, 'sobel'), [80,35]);
		usbTemplate = imresize(EdgeDetection.Filter(usbTemplate, 'sobel'), [80,40]);
		auxTemplate = imresize(EdgeDetection.Filter(auxTemplate, 'sobel'), [50,50]);
		mouseTemplate = imresize(EdgeDetection.Filter(mouseTemplate, 'sobel'), [60,60]);
		vgaTemplate = imresize(EdgeDetection.Filter(vgaTemplate, 'sobel'), [165,60]);

		%Binary
		%hdmiTemplate = BinaryImage.Binary(hdmiTemplate);
		%usbTemplate = BinaryImage.Binary(usbTemplate);
		%auxTemplate = BinaryImage.Binary(auxTemplate);


		Matches = []; % x; y; height; width; score; type
		scales = [0.7, 0.8];
		
		% find matches
		disp('hdmi');
		Matches = TemplateMatching.Match(Matches, image, hdmiTemplate, hdmi, 0.30, 70, scales);
		disp('mouse');
		Matches = TemplateMatching.Match(Matches, image, mouseTemplate, mouse, 0.25, 70, scales);
		disp('vga');
		Matches = TemplateMatching.Match(Matches, image, vgaTemplate, vga, 0.25, 70, scales);
		disp('usb');
		Matches = TemplateMatching.Match(Matches, image, usbTemplate, usb, 0.35, 70, scales);
		disp('aux');
		Matches = TemplateMatching.Match(Matches, image, auxTemplate, aux, 0.28, 70, scales);
		

		format shortg
		disp(Matches);

		% highlight matches in original
		result = TemplateMatching.DrawRectangles(imageCut, Matches);

		% color recognition
	% 	i = ColorRecognition.colors(original, 'blue');
	% 	imshow(i);


		% initialize GUI
		% GUI.init();

		% display image
		imshowpair(result, image, 'montage');
		disp('DONE');
        end
        
        function male(app)
            %mainFunc(app.Picture, 2);
            usb = 1;
		hdmi = 2;
		
               
		[x,y,z] = size(app.Picture);
		
		maxy = 800;
		maxx = 800;
		
		if y > maxy
			app.Picture.image = imresize(app.Picture, [(x * (maxy/y)), maxy]);
			
		end
		
		if x > maxx
			app.Picture.image = imresize(Picture, [maxx, (y * (maxx/x))]);
		end
		
		image = EdgeDetection.Filter(app.Picture.image, 'sobel');

		% load templates
		hdmiTemplate = imread('Images/male_hdmi_template.jpg');
		usbTemplate = imread('Images/male_usb_template.jpg');
		
		hdmiTemplate = EdgeDetection.Filter(hdmiTemplate, 'sobel');
		usbTemplate = EdgeDetection.Filter(usbTemplate, 'sobel');
		
		Matches = [];
		
		%scales = flip(linspace(0.4, max((maxx/x),(maxy/y)),7)); % 7 sizes up to the size of scaled image 
		scales = flip(linspace(0.2, 2, 20)); % 20 sizes between 0.2 and 2 

		disp('hdmi');
		Matches = TemplateMatching.Match(Matches, image, hdmiTemplate, hdmi, 0.3, 200, scales);
		disp('usb');
		Matches = TemplateMatching.Match(Matches, image, usbTemplate, usb, 0.1, 200, scales);
		
		format shortg
		disp(Matches);

		% highlight matches in original
		result = TemplateMatching.DrawRectangles(original, Matches);
		imshowpair(result, image, 'montage');
		disp('DONE');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            
        end

        % Button pushed function: MaleButton
        function MaleButtonPushed(app, event)
            app.male(app);
        end

        % Button pushed function: ZuschneidenButton
        function ZuschneidenButtonPushed(app, event)
            app.Cut(app, app.Picture);
        end

        % Button pushed function: FemaleButton
        function FemaleButtonPushed(app, event)
            app.female(app);
        end

        % Button pushed function: BildauswaehlenButton
        function BildauswaehlenButtonPushed(app, event)
            [File_Name, Path_Name] = uigetfile('*.*');
            %axes(app.UIAxes)
            app.Picture = imread(['Images/' File_Name]);
            imshow(['Images/' File_Name], 'Parent', app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MaleButton = uibutton(app.UIFigure, 'push');
            app.MaleButton.ButtonPushedFcn = createCallbackFcn(app, @MaleButtonPushed, true);
            app.MaleButton.Text = 'Male';
            app.FemaleButton = uibutton(app.UIFigure, 'push');
            app.FemaleButton.ButtonPushedFcn = createCallbackFcn(app, @FemaleButtonPushed, true);
            app.FemaleButton.Text = {'Female'; ''};
            app.ZuschneidenButton = uibutton(app.UIFigure, 'push');
            app.ZuschneidenButton.ButtonPushedFcn = createCallbackFcn(app, @ZuschneidenButtonPushed, true);
            app.ZuschneidenButton.Text = 'Zuschneiden';
            app.BildauswaehlenButton = uibutton(app.UIFigure, 'push');
            app.BildauswaehlenButton.ButtonPushedFcn = createCallbackFcn(app, @BildauswaehlenButtonPushed, true);
            app.BildauswaehlenButton.Text = 'Bild auswaehlen';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bild')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end